classdef app_registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegistrationGUIv1UIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        MovingFrameButton             matlab.ui.control.Button
        RefFrameButton                matlab.ui.control.Button
        MovingFrameFileNameText       matlab.ui.control.EditField
        ReferenceFrameFileNameText    matlab.ui.control.EditField
        FrameindexSliderLabel         matlab.ui.control.Label
        FrameindexSlider              matlab.ui.control.Slider
        TotalframesLabel              matlab.ui.control.Label
        PointControlButton            matlab.ui.control.Button
        MovingEnhanceSlider           matlab.ui.control.Slider
        ReferenceEnhanceSlider        matlab.ui.control.Slider
        MovingFrameIntensityLabel     matlab.ui.control.Label
        ReferenceFrameIntensityLabel  matlab.ui.control.Label
        FrameindexEditField           matlab.ui.control.NumericEditField
        TotalFramesEditField          matlab.ui.control.NumericEditField
        AdjustVisualizationLabel      matlab.ui.control.Label
        UseControlpointsLabel         matlab.ui.control.Label
        ManualRegistrationLabel       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        TranslationTab                matlab.ui.container.Tab
        TranslationxSliderLabel       matlab.ui.control.Label
        TranslationxSlider            matlab.ui.control.Slider
        TranslationySliderLabel       matlab.ui.control.Label
        TranslationySlider            matlab.ui.control.Slider
        TranslationxEditField         matlab.ui.control.NumericEditField
        TranslationyEditField         matlab.ui.control.NumericEditField
        ScalingTab                    matlab.ui.container.Tab
        ScalexEditField               matlab.ui.control.NumericEditField
        ScaleyEditField               matlab.ui.control.NumericEditField
        ScalexSliderLabel             matlab.ui.control.Label
        ScalexSlider                  matlab.ui.control.Slider
        ScaleySliderLabel             matlab.ui.control.Label
        ScaleySlider                  matlab.ui.control.Slider
        RotationTab                   matlab.ui.container.Tab
        AngleEditField                matlab.ui.control.NumericEditField
        AngleSliderLabel              matlab.ui.control.Label
        AngleSlider                   matlab.ui.control.Slider
        ExporttransformationButton    matlab.ui.control.Button
        ApplytoallframesButton        matlab.ui.control.Button
        PointControlResetButton       matlab.ui.control.Button
        Specifymovingandreferenceimage23DtiffstackLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        moving_frame_filename % Description
        moving_frame_filepath
        reference_frame_filename
        reference_frame_filepath
        
        total_frame
        frame_ind
        moving_frame
        reference_frame
        
        moving_frame_intensity
        reference_frame_intensity
        
        translation_x
        translation_y
        scale_x
        scale_y
        rotation
        tform0
        tform1
        tform2
        tform3
        
        tform_en
    end
    
    methods (Access = private)


        function updatePreview(app)
            
            if sum(app.moving_frame_filename) == 0 || sum(app.reference_frame_filename) == 0
                ;
            else
                if app.tform_en == 1   
                    if ~isequal(app.tform0.T, eye(3))
                        temp = imwarp(app.moving_frame, app.tform0, 'OutputView', imref2d(size(app.reference_frame)));
                    else
                        temp = imresize(app.moving_frame, size(app.reference_frame));
                    end
                    tform = affine2d(app.tform1.T * app.tform2.T * app.tform3.T);
                    centerOutput = affineOutputView(size(temp),tform,'BoundsStyle','CenterOutput');                
                    temp = imwarp(temp, tform, 'OutputView', centerOutput);
                else
                    temp = app.moving_frame;
                end
                imshowpair(app.moving_frame_intensity * normalize(app, temp), app.reference_frame_intensity * normalize(app, app.reference_frame), 'Scaling','none' ,'Parent', app.UIAxes);
                axis(app.UIAxes, "tight");   
            end

        end
        
        function image_normalized = normalize(app, image)
            image_normalized = image / max(image(:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.moving_frame_filename = '';
            app.moving_frame_filepath = './';
            app.reference_frame_filename = '';
            app.reference_frame_filepath = './';
            
            app.total_frame = 1;
            app.frame_ind = 1;
            app.moving_frame_intensity = 1;
            app.reference_frame_intensity = 1;
            
            app.translation_x = 0;
            app.translation_y = 0;
            app.scale_x = 1;
            app.scale_y = 1;
            app.rotation = 0;
                
            app.tform0 = affine2d(eye(3));
            app.tform1 = affine2d(eye(3));
            app.tform2 = affine2d(eye(3));
            app.tform3 = affine2d(eye(3));
            
            app.tform_en = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RegistrationGUIv1UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {710, 710};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {475, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: MovingFrameButton
        function MovingFrameButtonPushed(app, event)
            [app.moving_frame_filename, app.moving_frame_filepath] = uigetfile({'*.tif; *.tiff'}, 'Pick frame to be registered', app.moving_frame_filepath);
            drawnow;
            figure(app.RegistrationGUIv1UIFigure)
            if app.moving_frame_filepath == 0
                app.moving_frame_filepath = './';
            end
            app.MovingFrameFileNameText.Value = num2str(app.moving_frame_filename);
            
            try
                app.moving_frame = double(imread(fullfile(app.moving_frame_filepath, app.moving_frame_filename), app.frame_ind));
                app.tform_en = 0;
            catch ME
                if strcmp(ME.identifier, 'MATLAB:imagesci:rtifc:invalidDirIndex')
                    disp('Frame index out of range. Please choose another frame index.')
                end  
            end
            
        end

        % Button pushed function: RefFrameButton
        function RefFrameButtonPushed(app, event)
            [app.reference_frame_filename, app.reference_frame_filepath] = uigetfile({'*.tif; *.tiff'}, 'Pick frame to register to', app.reference_frame_filepath);
            drawnow;
            figure(app.RegistrationGUIv1UIFigure)
            if app.reference_frame_filepath == 0
                app.reference_frame_filepath = './';
            end
            app.ReferenceFrameFileNameText.Value = num2str(app.reference_frame_filename);

            try
                app.reference_frame = double(imread(fullfile(app.reference_frame_filepath, app.reference_frame_filename), app.frame_ind));
                app.tform_en = 0;
            catch ME
                if strcmp(ME.identifier, 'MATLAB:imagesci:rtifc:invalidDirIndex')
                    disp('Frame index out of range. Please choose another frame index.')
                end  
            end
            
        end

        % Value changed function: MovingFrameFileNameText
        function MovingFrameFileNameTextValueChanged(app, event)
            app.moving_frame_filename = app.MovingFrameFileNameText.Value;      
        end

        % Value changed function: ReferenceFrameFileNameText
        function ReferenceFrameFileNameTextValueChanged(app, event)
            app.reference_frame_filename = app.ReferenceFrameFileNameText.Value;
        end

        % Value changed function: TotalFramesEditField
        function TotalFramesEditFieldValueChanged(app, event)
            value = round(app.TotalFramesEditField.Value);
            if value > 1
                app.FrameindexSlider.Limits = [1 value];
            else
                app.FrameindexSlider.Limits = [0 value];
            end
            app.total_frame = value;
        end

        % Value changing function: FrameindexSlider
        function FrameindexSliderValueChanging(app, event)

            changingValue = round(event.Value);
            app.frame_ind = changingValue;
            app.FrameindexEditField.Value = changingValue;
            try
                app.moving_frame = double(imread(fullfile(app.moving_frame_filepath, app.moving_frame_filename), round(changingValue)));
                app.reference_frame = double(imread(fullfile(app.reference_frame_filepath, app.reference_frame_filename), round(changingValue)));
                updatePreview(app);                    
            catch ME
                if strcmp(ME.identifier, 'MATLAB:imagesci:rtifc:invalidDirIndex')
                    disp('Frame index out of range. Please choose another frame index.')
                end                    
            end

        end

        % Value changed function: FrameindexEditField
        function FrameindexEditFieldValueChanged(app, event)
            value = round(app.FrameindexEditField.Value);
            app.FrameindexEditField.Value = value;
            app.FrameindexSlider.Value = value;
            app.frame_ind = value;           
            try                
                app.moving_frame = double(imread(fullfile(app.moving_frame_filepath, app.moving_frame_filename),value));
                app.reference_frame = double(imread(fullfile(app.reference_frame_filepath, app.reference_frame_filename), value));
                updatePreview(app);                    
            catch ME
                if strcmp(ME.identifier, 'MATLAB:imagesci:rtifc:invalidDirIndex')
                    disp('Frame index out of range. Please choose another frame index.')
                end                    
            end
        end

        % Callback function
        function TotFrameTextValueChanged(app, event)
            value = round(str2num(app.TotFrameText.Value));
            app.FrameindexSlider.Limits = [0, value];
        end

        % Button pushed function: PointControlButton
        function PointControlButtonPushed(app, event)
            [selectedMovingPoints,selectedFixedPoints] = cpselect(normalize(app, app.moving_frame), normalize(app, app.reference_frame) , 'Wait', true);
            app.tform0 = fitgeotrans(selectedMovingPoints,selectedFixedPoints,'similarity');
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changing function: MovingEnhanceSlider
        function MovingEnhanceSliderValueChanging(app, event)
            app.moving_frame_intensity = event.Value;
            updatePreview(app); 
        end

        % Value changing function: ReferenceEnhanceSlider
        function ReferenceEnhanceSliderValueChanging(app, event)
            app.reference_frame_intensity = event.Value;
            updatePreview(app);        
        end

        % Button pushed function: PointControlResetButton
        function PointControlResetButtonPushed(app, event)
            app.tform0 = affine2d(eye(3));
            app.tform_en = 0;
            updatePreview(app);  
        end

        % Value changing function: TranslationxSlider
        function TranslationxSliderValueChanging(app, event)
            changingValue = round(event.Value*100)/100;
            app.TranslationxEditField.Value = changingValue;
            app.translation_x = changingValue;
            app.tform1 = affine2d([ 1 0 0;...
                                    0 1 0; ...
                                    app.translation_x app.translation_y 1]);
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changed function: TranslationxEditField
        function TranslationxEditFieldValueChanged(app, event)
            value = app.TranslationxEditField.Value;
            if value > app.TranslationxSlider.Limits(2)
                app.TranslationxSlider.Limits(2) = 1.5*value;
            elseif value < 20 && value > -20
                app.TranslationxSlider.Limits(2) = 20;
                app.TranslationxSlider.Limits(1) = -20;
            elseif value < app.TranslationxSlider.Limits(1)
                app.TranslationxSlider.Limits(1) = 1.5*value;                
            else
                ;
            end
            app.TranslationxSlider.Value = value;
            app.translation_x = value;
            app.tform1 = affine2d([ 1 0 0;...
                                    0 1 0; ...
                                    app.translation_x app.translation_y 1]);
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changing function: TranslationySlider
        function TranslationySliderValueChanging(app, event)
            changingValue = round(event.Value*100)/100;
            app.TranslationyEditField.Value = changingValue;
            app.translation_y = changingValue;
            app.tform1 = affine2d([ 1 0 0;...
                                    0 1 0; ...
                                    app.translation_x app.translation_y 1]);
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changed function: TranslationyEditField
        function TranslationyEditFieldValueChanged(app, event)
            value = app.TranslationyEditField.Value;
            if value > app.TranslationySlider.Limits(2)
                app.TranslationySlider.Limits(2) = 1.5*value;
            elseif value < 20 && value > -20
                app.TranslationySlider.Limits(2) = 20;
                app.TranslationySlider.Limits(1) = -20;
            elseif value < app.TranslationySlider.Limits(1)
                app.TranslationySlider.Limits(1) = 1.5*value;                
            else
                ;
            end
            app.TranslationySlider.Value = value;
            app.translation_y = value;
            app.tform1 = affine2d([ 1 0 0;...
                                    0 1 0; ...
                                    app.translation_x app.translation_y 1]); 
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changing function: ScalexSlider
        function ScalexSliderValueChanging(app, event)
            changingValue = round(event.Value*100)/100;
            app.ScalexEditField.Value = changingValue;
            app.scale_x = changingValue;
            app.tform2 = affine2d([ app.scale_x 0 0;...
                                    0 app.scale_y 0; ...
                                    0 0 1]);
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changing function: ScaleySlider
        function ScaleySliderValueChanging(app, event)
            changingValue = round(event.Value*100)/100;
            app.ScaleyEditField.Value = changingValue;
            app.scale_y = changingValue;
            app.tform2 = affine2d([ app.scale_x 0 0;...
                                    0 app.scale_y 0; ...
                                    0 0 1]);    
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changed function: ScalexEditField
        function ScalexEditFieldValueChanged(app, event)
            value = app.ScalexEditField.Value;
            if value > app.ScalexSlider.Limits(2)
                app.ScalexSlider.Limits(2) = 1.5*value;
            elseif value < 1.3 && value > 0.7
                app.ScalexSlider.Limits(2) = 1.3;
                app.ScalexSlider.Limits(1) = 0.7;
            elseif value < app.ScalexSlider.Limits(1)
                app.ScalexSlider.Limits(1) = 0.5*value;                
            else
                ;
            end
            app.ScalexSlider.Value = value;
            app.scale_x = value;
            app.tform2 = affine2d([ app.scale_x 0 0;...
                                    0 app.scale_y 0; ...
                                    0 0 1]);  
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changed function: ScaleyEditField
        function ScaleyEditFieldValueChanged(app, event)
            value = app.ScaleyEditField.Value;
            if value > app.ScaleySlider.Limits(2)
                app.ScaleySlider.Limits(2) = 1.5*value;
            elseif value < 1.3 && value > 0.7
                app.ScaleySlider.Limits(2) = 1.3;
                app.ScaleySlider.Limits(1) = 0.7;
            elseif value < app.ScaleySlider.Limits(1)
                app.ScaleySlider.Limits(1) = 0.5*value;                
            else
                ;
            end
            app.ScaleySlider.Value = value;  
            app.scale_y = value;
            app.tform2 = affine2d([ app.scale_x 0 0;...
                                    0 app.scale_y 0; ...
                                    0 0 1]);  
            app.tform_en = 1;
            updatePreview(app); 
            
        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)
            changingValue = round(event.Value*100)/100;
            app.AngleEditField.Value = changingValue;
            app.rotation = changingValue;
            app.tform3 = affine2d([ cosd(app.rotation) sind(app.rotation) 0;...
                                    -sind(app.rotation) cosd(app.rotation) 0; ...
                                    0 0 1]); 
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            if value > app.AngleSlider.Limits(2)
                app.AngleSlider.Limits(2) = 1.5 * value;
            elseif value < 10 && value > -10
                app.AngleSlider.Limits = [-10,10];
            elseif value < app.AngleSlider.Limits(1)
                app.AngleSlider.Limits(1) = 1.5*value;
            end
            app.AngleSlider.Value = value;
            app.rotation = value;
            app.tform3 = affine2d([ cosd(app.rotation) sind(app.rotation) 0;...
                                    -sind(app.rotation) cosd(app.rotation) 0; ...
                                    0 0 1]); 
            app.tform_en = 1;
            updatePreview(app); 
        end

        % Button pushed function: ApplytoallframesButton
        function ApplytoallframesButtonPushed(app, event)
            if app.tform_en == 1
                disp("Start saving registered images...")
                for i=1:app.total_frame
                    app.moving_frame = double(imread(fullfile(app.moving_frame_filepath, app.moving_frame_filename), i));
                    app.reference_frame = double(imread(fullfile(app.reference_frame_filepath, app.reference_frame_filename), i));
                    temp1 = imwarp(app.moving_frame, app.tform0, 'OutputView', imref2d(size(app.reference_frame)));
                    tform = affine2d(app.tform1.T * app.tform2.T * app.tform3.T);
                    centerOutput = affineOutputView(size(temp1),tform,'BoundsStyle','CenterOutput');                
                    temp = imwarp(temp1, tform, 'OutputView', centerOutput);
                    if i==1
                        imwrite(uint16(temp), fullfile(app.moving_frame_filepath, ['REG_' app.moving_frame_filename]));
                    else
                        imwrite(uint16(temp), fullfile(app.moving_frame_filepath, ['REG_' app.moving_frame_filename]), 'WriteMode', 'append');
                    end
                end
                disp("Finished.")
            else
                disp("The transformation has not been specified.");
            end
        end

        % Button pushed function: ExporttransformationButton
        function ExporttransformationButtonPushed(app, event)
            tform = affine2d(app.tform1.T * app.tform2.T * app.tform3.T);
            disp("tform0")
            disp(app.tform0.T)
            disp("tform")
            disp(tform.T)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegistrationGUIv1UIFigure = uifigure('Visible', 'off');
            app.RegistrationGUIv1UIFigure.Name = 'RegistrationGUI v1';
            app.RegistrationGUIv1UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RegistrationGUIv1UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MovingFrameButton = uibutton(app.LeftPanel, 'push');
            app.MovingFrameButton.ButtonPushedFcn = createCallbackFcn(app, @MovingFrameButtonPushed, true);
            app.MovingFrameButton.Text = 'Moving Frame';
            app.RefFrameButton = uibutton(app.LeftPanel, 'push');
            app.RefFrameButton.ButtonPushedFcn = createCallbackFcn(app, @RefFrameButtonPushed, true);
            app.RefFrameButton.Text = {'Ref. Frame'; ''};
            app.MovingFrameFileNameText = uieditfield(app.LeftPanel, 'text');
            app.MovingFrameFileNameText.ValueChangedFcn = createCallbackFcn(app, @MovingFrameFileNameTextValueChanged, true);
            app.MovingFrameFileNameText.Editable = 'off';
            app.ReferenceFrameFileNameText = uieditfield(app.LeftPanel, 'text');
            app.ReferenceFrameFileNameText.ValueChangedFcn = createCallbackFcn(app, @ReferenceFrameFileNameTextValueChanged, true);
            app.ReferenceFrameFileNameText.Editable = 'off';
            app.FrameindexSliderLabel = uilabel(app.LeftPanel);
            app.FrameindexSliderLabel.Text = 'Frame index';
            app.FrameindexSlider = uislider(app.LeftPanel);
            app.FrameindexSlider.Limits = [0 1];
            app.FrameindexSlider.ValueChangingFcn = createCallbackFcn(app, @FrameindexSliderValueChanging, true);
            app.TotalframesLabel = uilabel(app.LeftPanel);
            app.TotalframesLabel.Text = 'Total frames';
            app.PointControlButton = uibutton(app.LeftPanel, 'push');
            app.PointControlButton.ButtonPushedFcn = createCallbackFcn(app, @PointControlButtonPushed, true);
            app.PointControlButton.Text = 'Control Points Registration';
            app.MovingEnhanceSlider = uislider(app.LeftPanel);
            app.MovingEnhanceSlider.Limits = [0 1];
            app.MovingEnhanceSlider.ValueChangingFcn = createCallbackFcn(app, @MovingEnhanceSliderValueChanging, true);
            app.MovingEnhanceSlider.Value = 1;
            app.ReferenceEnhanceSlider = uislider(app.LeftPanel);
            app.ReferenceEnhanceSlider.Limits = [0 1];
            app.ReferenceEnhanceSlider.ValueChangingFcn = createCallbackFcn(app, @ReferenceEnhanceSliderValueChanging, true);
            app.ReferenceEnhanceSlider.Value = 1;
            app.MovingFrameIntensityLabel = uilabel(app.LeftPanel);
            app.MovingFrameIntensityLabel.Text = 'Moving Frame Intensity';
            app.ReferenceFrameIntensityLabel = uilabel(app.LeftPanel);
            app.ReferenceFrameIntensityLabel.Text = 'Reference Frame Intensity';
            app.FrameindexEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrameindexEditField.ValueChangedFcn = createCallbackFcn(app, @FrameindexEditFieldValueChanged, true);
            app.TotalFramesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TotalFramesEditField.Limits = [1 Inf];
            app.TotalFramesEditField.ValueChangedFcn = createCallbackFcn(app, @TotalFramesEditFieldValueChanged, true);
            app.TotalFramesEditField.Value = 1;
            app.AdjustVisualizationLabel = uilabel(app.LeftPanel);
            app.AdjustVisualizationLabel.Text = 'Adjust Visualization ';
            app.UseControlpointsLabel = uilabel(app.LeftPanel);
            app.UseControlpointsLabel.Text = 'Use Control points';
            app.ManualRegistrationLabel = uilabel(app.LeftPanel);
            app.ManualRegistrationLabel.Text = 'Manual Registration';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TranslationTab = uitab(app.TabGroup);
            app.TranslationTab.Title = '1. Translation';
            app.TranslationxSliderLabel = uilabel(app.TranslationTab);
            app.TranslationxSliderLabel.Text = 'Translation x';
            app.TranslationxSlider = uislider(app.TranslationTab);
            app.TranslationxSlider.Limits = [-20 20];
            app.TranslationxSlider.ValueChangingFcn = createCallbackFcn(app, @TranslationxSliderValueChanging, true);
            app.TranslationySliderLabel = uilabel(app.TranslationTab);
            app.TranslationySliderLabel.Text = 'Translation y';
            app.TranslationySlider = uislider(app.TranslationTab);
            app.TranslationySlider.Limits = [-20 20];
            app.TranslationySlider.ValueChangingFcn = createCallbackFcn(app, @TranslationySliderValueChanging, true);
            app.TranslationxEditField = uieditfield(app.TranslationTab, 'numeric');
            app.TranslationxEditField.ValueDisplayFormat = '%5.2f';
            app.TranslationxEditField.ValueChangedFcn = createCallbackFcn(app, @TranslationxEditFieldValueChanged, true);
            app.TranslationyEditField = uieditfield(app.TranslationTab, 'numeric');
            app.TranslationyEditField.ValueDisplayFormat = '%5.2f';
            app.TranslationyEditField.ValueChangedFcn = createCallbackFcn(app, @TranslationyEditFieldValueChanged, true);
            app.ScalingTab = uitab(app.TabGroup);
            app.ScalingTab.Title = '2. Scaling';
            app.ScalexEditField = uieditfield(app.ScalingTab, 'numeric');
            app.ScalexEditField.ValueDisplayFormat = '%5.2f';
            app.ScalexEditField.ValueChangedFcn = createCallbackFcn(app, @ScalexEditFieldValueChanged, true);
            app.ScalexEditField.Value = 1;
            app.ScaleyEditField = uieditfield(app.ScalingTab, 'numeric');
            app.ScaleyEditField.ValueDisplayFormat = '%5.2f';
            app.ScaleyEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleyEditFieldValueChanged, true);
            app.ScaleyEditField.Value = 1;
            app.ScalexSliderLabel = uilabel(app.ScalingTab);
            app.ScalexSliderLabel.Text = 'Scale x';
            app.ScalexSlider = uislider(app.ScalingTab);
            app.ScalexSlider.Limits = [0.7 1.3];
            app.ScalexSlider.ValueChangingFcn = createCallbackFcn(app, @ScalexSliderValueChanging, true);
            app.ScalexSlider.Value = 1;
            app.ScaleySliderLabel = uilabel(app.ScalingTab);
            app.ScaleySliderLabel.Text = 'Scale y';
            app.ScaleySlider = uislider(app.ScalingTab);
            app.ScaleySlider.Limits = [0.7 1.3];
            app.ScaleySlider.ValueChangingFcn = createCallbackFcn(app, @ScaleySliderValueChanging, true);
            app.ScaleySlider.Value = 1;
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = '3. Rotation';
            app.AngleEditField = uieditfield(app.RotationTab, 'numeric');
            app.AngleEditField.ValueDisplayFormat = '%5.2f';
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleSliderLabel = uilabel(app.RotationTab);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.RotationTab);
            app.AngleSlider.Limits = [-10 10];
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.ExporttransformationButton = uibutton(app.LeftPanel, 'push');
            app.ExporttransformationButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttransformationButtonPushed, true);
            app.ExporttransformationButton.Text = 'Export transformation';
            app.ApplytoallframesButton = uibutton(app.LeftPanel, 'push');
            app.ApplytoallframesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallframesButtonPushed, true);
            app.ApplytoallframesButton.Text = 'Apply to all frames';
            app.PointControlResetButton = uibutton(app.LeftPanel, 'push');
            app.PointControlResetButton.ButtonPushedFcn = createCallbackFcn(app, @PointControlResetButtonPushed, true);
            app.PointControlResetButton.Text = 'Reset';
            app.Specifymovingandreferenceimage23DtiffstackLabel = uilabel(app.LeftPanel);
            app.Specifymovingandreferenceimage23DtiffstackLabel.Text = 'Specify moving and reference image (2/3D tiff stack)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            % Show the figure after all components are created
            app.RegistrationGUIv1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_registration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RegistrationGUIv1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegistrationGUIv1UIFigure)
        end
    end
end